classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ThresholdingLabel              matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        OperatorLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        OptionsLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        ResultLabel                    matlab.ui.control.Label
        InputLabel                     matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        FruitImageChoiceDropDown       matlab.ui.control.DropDown
        FruitImageChoiceDropDownLabel  matlab.ui.control.Label
        APPLYButton                    matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        ImageSegmentationLabel         matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
           [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           figure(app.UIFigure);
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           app.UIAxes.Visible = 'on';
           app.UIAxes.XLim = [0 width(ImageFile)];
           app.UIAxes.YLim = [0 height(ImageFile)];
           imshow(ImageFile, 'Parent', app.UIAxes);
           app.Image.Visible = 'off';
           app.APPLYButton.Enable = 'on';
%            ChooseMethodButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Image.Visible = 'on';
            app.UIAxes.Visible = 'off';
            delete(app.UIAxes.Children);
            app.APPLYButton.Enable = 'off';
            
%             app.ChooseMethodButtonGroup.SelectedObject = app.LowPassFilterButton;
%             app.FilterFunctionButtonGroup.SelectedObject = app.IdealButton;
%             app.CutoffFrequencySlider.Value = 1;
%             app.AlphaSlider.Value = 1;
%             app.FilterOrderSlider.Value = 1;
%             ChooseMethodButtonGroupSelectionChanged(app, event);
        end

        % Callback function
        function ChooseMethodButtonGroupSelectionChanged(app, event)
            if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter" ||...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "Enhance Filter")
                app.FilterFunctionButtonGroup.Visible = 'on';
                app.CutoffFrequencySlider.Visible = 'on';
                app.CutoffFrequencySliderLabel.Visible ='on';
            else
                app.FilterFunctionButtonGroup.Visible = 'off';
                app.CutoffFrequencySlider.Visible = 'off';
                app.CutoffFrequencySliderLabel.Visible ='off';
            end
            if (~isempty(app.UIAxes.Children) && ...
                ~isempty(app.ChooseMethodButtonGroup.SelectedObject))
                if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                    app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter")
                    app.FilterFunctionButtonGroup.Visible = 'on';
                    if (~isempty(app.FilterFunctionButtonGroup.SelectedObject))
                        app.APPLYButton.Enable = 'on';
                    end
                else
                    app.APPLYButton.Enable = 'on';
                end
            end

            selectedFilter = app.FilterFunctionButtonGroup.SelectedObject;
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;

            RESETButtonPushed(app, event);

            if (selectedMethod.Text == "Remove Noise")
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
                app.AlphaSlider.Visible = 'off';
                app.AlphaSliderLabel.Visible = 'off';
                app.Image.Visible = 'off';
                app.FruitImageChoiceDropDown.Visible = 'on';
                app.FruitImageChoiceDropDownLabel.Visible = 'on';
                FruitImageChoiceDropDownValueChanged(app, event);
            else
                app.FruitImageChoiceDropDown.Visible = 'off';
                app.FruitImageChoiceDropDownLabel.Visible = 'off';
                if (selectedMethod.Text == "Enhance Filter")
                    app.AlphaSlider.Visible = 'on';
                    app.AlphaSliderLabel.Visible = 'on';
                else
                    app.AlphaSlider.Visible = 'off';
                    app.AlphaSliderLabel.Visible = 'off';
                end
                if (selectedFilter.Text ~= "Butterworth")
                    app.FilterOrderSlider.Visible = 'off';
                    app.FilterOrderSliderLabel.Visible = 'off';
                else
                    app.FilterOrderSlider.Visible = 'on';
                    app.FilterOrderSliderLabel.Visible = 'on';
                end
            end
        end

        % Callback function
        function FilterFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterFunctionButtonGroup.SelectedObject;
            if (selectedButton.Text == "Butterworth")
                app.FilterOrderSlider.Visible = 'on';
                app.FilterOrderSliderLabel.Visible = 'on';
            else
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
            end
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;
            if (selectedMethod.Text == "Remove Noise")
                scriptMap = containers.Map(["res2/9.png", "res2/11.jpg", "res2/14.jpg"], ["t4_9png" "t4_11jpg" "t4_14jpg"]);
                disp(scriptMap(app.FruitImageChoiceDropDown.Value));
                run(scriptMap(app.FruitImageChoiceDropDown.Value));
            else
                img = app.UIAxes.Children.CData;
                filterType = app.FilterFunctionButtonGroup.SelectedObject.Text;
                cutOff = app.CutoffFrequencySlider.Value;
                filterOrd = app.FilterOrderSlider.Value;
                alpha = app.AlphaSlider.Value;
    
                disp(filterType);
                disp(cutOff);
         
                if (selectedMethod.Text == "Low Pass Filter")
                    LowPassFilter(img, filterType, cutOff, filterOrd);
                elseif (selectedMethod.Text == "High Pass Filter")
                    HighPassFilter(img, filterType, cutOff, filterOrd);
                elseif (selectedMethod.Text == "Enhance Filter")
                    res = HighBoost(img, alpha, filterType, cutOff, filterOrd);
                    figure,imshow(img);
                    figure,imshow(res);
                end
            end
        end

        % Value changed function: FruitImageChoiceDropDown
        function FruitImageChoiceDropDownValueChanged(app, event)
            fullname = app.FruitImageChoiceDropDown.Value;
            figure(app.UIFigure);
            ImageFile = imread(fullname);
            app.UIAxes.Visible = 'on';
            app.UIAxes.XLim = [0 width(ImageFile)];
            app.UIAxes.YLim = [0 height(ImageFile)];
            imshow(ImageFile, 'Parent', app.UIAxes);
            app.Image.Visible = 'off';
            app.APPLYButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {548, 548};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OptionsLabel = uilabel(app.LeftPanel);
            app.OptionsLabel.Text = 'Options';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Laplace', 'Gaussian', 'LoG', 'Robert', 'Prewitt', 'Sobel', 'Canny'};
            app.DropDown.Value = 'Laplace';
            app.OperatorLabel = uilabel(app.LeftPanel);
            app.OperatorLabel.Text = 'Operator';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'Hystheresis'};
            app.DropDown_2.Value = 'Hystheresis';
            app.ThresholdingLabel = uilabel(app.LeftPanel);
            app.ThresholdingLabel.Text = 'Thresholding';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ImageSegmentationLabel = uilabel(app.GridLayout2);
            app.ImageSegmentationLabel.Text = 'Image Segmentation';
            app.RESETButton = uibutton(app.GridLayout2, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.APPLYButton = uibutton(app.GridLayout2, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Enable = 'off';
            app.APPLYButton.Text = 'APPLY';
            app.FruitImageChoiceDropDownLabel = uilabel(app.GridLayout2);
            app.FruitImageChoiceDropDownLabel.Visible = 'off';
            app.FruitImageChoiceDropDownLabel.Text = 'Fruit Image Choice';
            app.FruitImageChoiceDropDown = uidropdown(app.GridLayout2);
            app.FruitImageChoiceDropDown.Items = {'res2/9.png', 'res2/11.jpg', 'res2/14.jpg'};
            app.FruitImageChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @FruitImageChoiceDropDownValueChanged, true);
            app.FruitImageChoiceDropDown.Visible = 'off';
            app.FruitImageChoiceDropDown.Value = 'res2/9.png';
            app.Image2 = uiimage(app.GridLayout2);
            app.InputLabel = uilabel(app.GridLayout2);
            app.InputLabel.Text = 'Input';
            app.ResultLabel = uilabel(app.GridLayout2);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end